classdef Part2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        FrequencyGHzEditField        matlab.ui.control.NumericEditField
        FrequencyGHzEditFieldLabel   matlab.ui.control.Label
        Noted1isthedistancefromthetransmittertothetopoftheobjectLabel  matlab.ui.control.Label
        EntertheparamatersandclickentertocalculatepathlossindBLabel  matlab.ui.control.Label
        KnifeModelPathLossCalculatorLabel  matlab.ui.control.Label
        hmEditField                  matlab.ui.control.NumericEditField
        hmEditFieldLabel             matlab.ui.control.Label
        d2mEditField                 matlab.ui.control.NumericEditField
        d2mEditFieldLabel            matlab.ui.control.Label
        d1mEditField                 matlab.ui.control.NumericEditField
        d1mEditFieldLabel            matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        KnifeParamterEditField       matlab.ui.control.NumericEditField
        KnifeParamterEditFieldLabel  matlab.ui.control.Label
        PathLossdBEditField          matlab.ui.control.NumericEditField
        PathLossdBEditFieldLabel     matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: LeftPanel
        function LeftPanelButtonDown(app, event)
            %%Input Variables
            h = app.hmEditField.Value;
            d1 = app.d1mEditField.Value;
            d2 = app.d2mEditField.Value;
            %%Input Lambda (c/f)
            l  = (3*10^8) / (app.FrequencyGHzEditField.Value * 10^9);
            %%Calculate v
            if ( (l > 0) && ( d1 > 0 ) && (d2 > 0) )
                v = h * sqrt( (2*(d1+d2)) / (l * d1 * d2));
            else
                v = 0; %%Condition to avoid dividing by zero
            end
            %%Find Loss depending on v
            if (v < - 1) 
               PL = 0;           
            elseif( (v >= -0.8) && (v < 0) ) 
               PL = 20*log10(0.5 - 0.62*v);         
            elseif( (v >= 0) && (v < 1) )
               PL = 20*log10(0.5*exp(-0.95*v));
            elseif( (v >= 1) && (v <= 2.4) )
               PL = 20*log10(0.4 - sqrt(0.1184 - (0.38-0.1*v)^2));
            elseif (v > 2.4)
               PL = 20*log10(0.225/v);
            end
            %%Update Knife Parameter Button
            app.KnifeParamterEditField.Value = v;
            
            %%Update Path Loss Parameter Button
            app.PathLossdBEditField.Value = PL;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {269, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.ButtonDownFcn = createCallbackFcn(app, @LeftPanelButtonDown, true);
            app.d1mEditFieldLabel = uilabel(app.LeftPanel);
            app.d1mEditFieldLabel.Text = 'd1 (m)';
            app.d1mEditField = uieditfield(app.LeftPanel, 'numeric');
            app.d2mEditFieldLabel = uilabel(app.LeftPanel);
            app.d2mEditFieldLabel.Text = 'd2 (m) ';
            app.d2mEditField = uieditfield(app.LeftPanel, 'numeric');
            app.hmEditFieldLabel = uilabel(app.LeftPanel);
            app.hmEditFieldLabel.Text = 'h (m)';
            app.hmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.KnifeModelPathLossCalculatorLabel = uilabel(app.LeftPanel);
            app.KnifeModelPathLossCalculatorLabel.Text = 'Knife Model Path Loss Calculator';
            app.EntertheparamatersandclickentertocalculatepathlossindBLabel = uilabel(app.LeftPanel);
            app.EntertheparamatersandclickentertocalculatepathlossindBLabel.Text = 'Enter the paramaters and click enter to calculate path loss in dB.';
            app.Noted1isthedistancefromthetransmittertothetopoftheobjectLabel = uilabel(app.LeftPanel);
            app.Noted1isthedistancefromthetransmittertothetopoftheobjectLabel.Text = 'Note: d1 is the distance from the transmitter to the top of the object. d2 is the distance from the reciever to the top of the object. h is the height of the object relative to the top of the antenna';
            app.FrequencyGHzEditFieldLabel = uilabel(app.LeftPanel);
            app.FrequencyGHzEditFieldLabel.Text = 'Frequency (GHz)';
            app.FrequencyGHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'Figure 2.png';
            app.PathLossdBEditFieldLabel = uilabel(app.RightPanel);
            app.PathLossdBEditFieldLabel.Text = 'Path Loss (dB)';
            app.PathLossdBEditField = uieditfield(app.RightPanel, 'numeric');
            app.PathLossdBEditField.Editable = 'off';
            app.KnifeParamterEditFieldLabel = uilabel(app.RightPanel);
            app.KnifeParamterEditFieldLabel.Text = 'Knife Paramter';
            app.KnifeParamterEditField = uieditfield(app.RightPanel, 'numeric');
            app.KnifeParamterEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Part2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end